classdef app2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        TabGroup                    matlab.ui.container.TabGroup
        PortfolioviewTab            matlab.ui.container.Tab
        PortfoliolocationEditFieldLabel  matlab.ui.control.Label
        PortfoliolocationEditField  matlab.ui.control.EditField
        HistoricalDatalocationEditFieldLabel  matlab.ui.control.Label
        HistoricalDatalocationEditField  matlab.ui.control.EditField
        ForecastdataEditFieldLabel  matlab.ui.control.Label
        ForecastdataEditField       matlab.ui.control.EditField
        RunButton                   matlab.ui.control.Button
        VARSwitchLabel              matlab.ui.control.Label
        VARSwitch                   matlab.ui.control.Switch
        UITable                     matlab.ui.control.Table
        DateDatePickerLabel         matlab.ui.control.Label
        DateDatePicker              matlab.ui.control.DatePicker
        RiskvisualizationTab        matlab.ui.container.Tab
        UITable2                    matlab.ui.control.Table
        WeeksEditFieldLabel         matlab.ui.control.Label
        WeeksEditField              matlab.ui.control.NumericEditField
        UIAxes                      matlab.ui.control.UIAxes
        UIAxes2                     matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function
        function PortfoliolocationEditFieldValueChanged(app, event)
            
            
        end

        % Callback function
        function PricedatalocationEditFieldValueChanged(app, event)
            
            
        end

        % Callback function
        function ReturnslocationEditFieldValueChanged(app, event)
            
            
        end

        % Value changed function: DateDatePicker
        function DateDatePickerValueChanged(app, event)
            
            
        end

        % Value changed function: VARSwitch
        function VARSwitchValueChanged(app, event)
            VARperc = app.VARSwitch.Value;
            
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            try
                portdata = readtable(app.PortfoliolocationEditField.Value);
                ratesdata = readtimetable(app.HistoricalDatalocationEditField.Value);
                forecastdata = readtable(app.ForecastdataEditField.Value);
                setdate = app.DateDatePicker.Value;
                
                %Uncomment to allow restriction to currently owned options.
                portdata(isbetween(setdate,portdata.StartDate,portdata.EndDate)==false,:) = [];
                
                %Obtain exchange rate at time of purchase
                for i=1:height(portdata)
                    portdata.Spot(i)=ratesdata{portdata.StartDate(i),portdata.Currency(i)};
                end
                
                %Obtains expected exchange rate for each entry
                for i=1:height(portdata)
                    portdata.ExpRate(i)=forecastdata{portdata.EndDate(i),portdata.Currency(i)};
                end
                
                %Calculate expected return. Needs Strike Price/Rate.
%                 portdata.ExpReturns = portdata.ExpRate*portdata.Size-portdata.Premium;
%                 for i=1:height(portdata)
%                     if portdata.ExpRate(i)>=portdata.Strike(i) && portdata.Type(i)=='Call'
%                         portdata.ExpReturns(i)=-portdata.Premium(i)
%                     elseif portdata.ExpRate(i)<=portdata.Strike(i) && portdata.Type(i)=='Put'
%                         portdata.ExpReturns(i)=-portdata.Premium(i)
%                     end
%                 end
                
                %Put VaR and StD calculations here, I have no idea how this
                %works.


                %Greeks. Why are there multiple values? Something about
                %fair price value. Maybe that's how we get the transaction
                %price thing? Just read the document for calcGreeks().


                %This is just test code, ignore it
                %%analysisdata = innerjoin(portdata,ratesdata,'LeftKeys','Currency','EndDate','RightKeys','Date','Currency')
                %analysisdata = table(portdata.Identifier, portdata.Currency, ...
                 %   ratesdata.(portdata.Value), [2;2;2], ...
                  %  [3;3;3],[4;4;4],[5;5;5],[6;6;6], ...
                   % [1;1;1],[2;2;2],[3;3;3],[4;4;4],[5;5;5], ...
                    %'VariableNames',{'Identifier', 'Currency', 'Value(USD)', ...
                    %'Expected Return', 'Standard Deviation', 'VAR (Daily Parametric)', ...
                    %'VAR (Monthly Converted Parametric)', 'VAR (Monte Carlo)', ...
                    %'Delta', 'Gamma', 'Theta', 'Vega', 'Rho'});
                %analysisdata = table(portdata.x, portdata.y, [1;2;3]);
                
                %Display the data!
                app.UITable.Data = portdata;
                app.UITable.ColumnName = portdata.Properties.VariableNames;
            catch ME
                errordlg(ME.message)
            end
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.PortfolioviewTab = uitab(app.TabGroup);
            app.PortfolioviewTab.Title = 'Portfolio view';
            app.PortfoliolocationEditFieldLabel = uilabel(app.PortfolioviewTab);
            app.PortfoliolocationEditFieldLabel.Text = 'Portfolio location';
            app.PortfoliolocationEditField = uieditfield(app.PortfolioviewTab, 'text');
            app.HistoricalDatalocationEditFieldLabel = uilabel(app.PortfolioviewTab);
            app.HistoricalDatalocationEditFieldLabel.Text = 'Historical Data location';
            app.HistoricalDatalocationEditField = uieditfield(app.PortfolioviewTab, 'text');
            app.ForecastdataEditFieldLabel = uilabel(app.PortfolioviewTab);
            app.ForecastdataEditFieldLabel.Text = 'Forecast data';
            app.ForecastdataEditField = uieditfield(app.PortfolioviewTab, 'text');
            app.RunButton = uibutton(app.PortfolioviewTab, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.VARSwitchLabel = uilabel(app.PortfolioviewTab);
            app.VARSwitchLabel.Text = 'VAR Switch';
            app.VARSwitch = uiswitch(app.PortfolioviewTab, 'slider');
            app.VARSwitch.Items = {'5', '1'};
            app.VARSwitch.ValueChangedFcn = createCallbackFcn(app, @VARSwitchValueChanged, true);
            app.VARSwitch.Value = '5';
            app.UITable = uitable(app.PortfolioviewTab);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable.RowName = {};
            app.DateDatePickerLabel = uilabel(app.PortfolioviewTab);
            app.DateDatePickerLabel.Text = 'Date';
            app.DateDatePicker = uidatepicker(app.PortfolioviewTab);
            app.DateDatePicker.ValueChangedFcn = createCallbackFcn(app, @DateDatePickerValueChanged, true);
            app.RiskvisualizationTab = uitab(app.TabGroup);
            app.RiskvisualizationTab.Title = 'Risk visualization';
            app.UITable2 = uitable(app.RiskvisualizationTab);
            app.UITable2.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable2.RowName = {};
            app.WeeksEditFieldLabel = uilabel(app.RiskvisualizationTab);
            app.WeeksEditFieldLabel.Text = 'Weeks';
            app.WeeksEditField = uieditfield(app.RiskvisualizationTab, 'numeric');
            app.UIAxes = uiaxes(app.RiskvisualizationTab);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.RiskvisualizationTab);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end